classdef Flow2Power_Tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        SelectFlowDatancButton         matlab.ui.control.Button
        LoadPowerSurfacematButton      matlab.ui.control.Button
        QuickProcessButton             matlab.ui.control.Button
        SelectedFilesListBoxLabel      matlab.ui.control.Label
        SelectedFilesListBox           matlab.ui.control.ListBox
        IfnobottomdepthfordataLabel    matlab.ui.control.Label
        GroundMaskDepthmLabel          matlab.ui.control.Label
        GroundMaskDepthmEditField      matlab.ui.control.NumericEditField
        MinKiteDepthmEditField_2Label  matlab.ui.control.Label
        MinKiteDepthmEditField_2       matlab.ui.control.NumericEditField
        ProcessButton_2                matlab.ui.control.Button
        CenterPanel                    matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        UITable                        matlab.ui.control.Table
        ButtonGroup                    matlab.ui.container.ButtonGroup
        PowerkWButton                  matlab.ui.control.ToggleButton
        VelocitymsButton               matlab.ui.control.ToggleButton
        RightPanel                     matlab.ui.container.Panel
        UIAxes3                        matlab.ui.control.UIAxes
        SaveResultsmatButton           matlab.ui.control.Button
        DisplaySettingsButtonGroup     matlab.ui.container.ButtonGroup
        thPercentileSiteSelectionButton  matlab.ui.control.StateButton
        thPercentileSiteSelectionButton_2  matlab.ui.control.StateButton
        thPercentileSiteSelectionButton_3  matlab.ui.control.StateButton
        thPercentileSiteSelectionButton_4  matlab.ui.control.StateButton
        DropDown                       matlab.ui.control.DropDown
        SaveFigureImagespngButton      matlab.ui.control.Button
        UIAxes3_2                      matlab.ui.control.UIAxes
        GenerateHistogramButton        matlab.ui.control.Button
        GenerateMapButton              matlab.ui.control.Button
        UnitsMetricButtonGroup         matlab.ui.container.ButtonGroup
        MetricButton                   matlab.ui.control.ToggleButton
        EnglishButton                  matlab.ui.control.ToggleButton
        PercentileEditFieldLabel       matlab.ui.control.Label
        PercentileEditField            matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        Flow        % Flow Data Structure
        ALL         % All structure where data is stored
        PowSurf    % Power surface for altitude and flow speed
        monthList
        Months
        monthIndex
        mkDepth
        groundMaskDepth
        dataInput
        unit
        initial
        runLong
        magPerc
        powPerc
        magPercMax
        powPercMax
    end
    
    % These are the functions you can add can change
    methods (Access = private)
        
        % Thid function helps to make the plotting look good
        function colors = distinguishable_colors(app,n_colors,bg,func)
            % DISTINGUISHABLE_COLORS: pick colors that are maximally perceptually distinct
            
            % Copyright 2010-2011 by Timothy E. Holy
            % Parse the inputs

            bg = [1 1 1];  % default white background

            
            % Generate a sizable number of RGB triples. This represents our space of
            % possible choices. By starting in RGB space, we ensure that all of the
            % colors can be generated by the monitor.
            n_grid = 30;  % number of grid divisions along each axis in RGB space
            x = linspace(0,1,n_grid);
            [R,G,B] = ndgrid(x,x,x);
            rgb = [R(:) G(:) B(:)];
            if (n_colors > size(rgb,1)/3)
                error('You can''t readily distinguish that many colors');
            end
            
            % Convert to Lab color space, which more closely represents human
            % perception
            
            C = makecform('srgb2lab');
            lab = applycform(rgb,C);
            bglab = applycform(bg,C);

            % If the user specified multiple background colors, compute distances
            % from the candidate colors to the background colors
            mindist2 = inf(size(rgb,1),1);
            for i = 1:size(bglab,1)-1
                dX = bsxfun(@minus,lab,bglab(i,:)); % displacement all colors from bg
                dist2 = sum(dX.^2,2);  % square distance
                mindist2 = min(dist2,mindist2);  % dist2 to closest previously-chosen color
            end
            
            % Iteratively pick the color that maximizes the distance to the nearest
            % already-picked color
            colors = zeros(n_colors,3);
            lastlab = bglab(end,:);   % initialize by making the "previous" color equal to background
            for i = 1:n_colors
                dX = bsxfun(@minus,lab,lastlab); % displacement of last from all colors on list
                dist2 = sum(dX.^2,2);  % square distance
                mindist2 = min(dist2,mindist2);  % dist2 to closest previously-chosen color
                [~,index] = max(mindist2);  % find the entry farthest from all previously-chosen colors
                colors(i,:) = rgb(index,:);  % save for output
                lastlab = lab(index,:);  % prepare for next iteration
            end
        end
        
        % This runs when flow data is selected
        function loadFlowData(app)
%             matornc = questdlg('Load folder of .nc files',...
%                 'File Format',...
%                 'Folder of *.nc files');

%                 'Single *.mat file',...
%                 'Folder of *.nc files');
%             switch matornc
%                 case 'Folder of *.nc files'
                    app.ALL.path = uigetdir;
%                 case 'Single *.mat file'
%                     [FileName,PathName] = uigetfile('*.mat','Select *.mat flow data file');
%                     if FileName == 0
%                         return
%                     end
%                     app.Flow = load( fullfile(PathName,FileName));
%             end
            SelectedFilesListBoxValueChanged(app)
        end
        
        % This function determines units for metric and english switch
        function unitMultiplerUpdate(app)
            if strcmp(app.unit.name,'Metric')==true
                app.unit.powerMultipler    = 1    ;
                app.unit.velocityMultipler = 1    ;
                app.unit.distanceMultipler = 1    ;
                app.unit.powerUnitTitle    = 'kW' ;
                app.unit.velocityUnitTitle = 'm/s';
                app.unit.distanceUnitTitle = 'm'  ;
            else
                app.unit.powerMultipler    = 1      ;
                app.unit.velocityMultipler = 1.94384;
                app.unit.distanceMultipler = 3.28084;
                app.unit.powerUnitTitle    = 'kW'   ;
                app.unit.velocityUnitTitle = 'knots';
                app.unit.distanceUnitTitle = 'ft'   ;
            end
        end
        
        % This runs when power surface data is selected
        function loadPowSurfData(app)
            [FileName,PathName] = uigetfile('*.mat','Select *.mat flow data file');
            if FileName == 0
                return
            end
            app.ALL.powPath = fullfile(PathName,FileName);
            app.PowSurf = load(app.ALL.powPath);
            SelectedFilesListBoxValueChanged(app)
            
            [ndgFlwSpd,ndgAltitude]=ndgrid(app.PowSurf.flwSpd,[1:1:300]);
            app.PowSurf.Pnet = interpn(app.PowSurf.flwSpd,app.PowSurf.altitude,app.PowSurf.Pnet,ndgFlwSpd,ndgAltitude);
            app.PowSurf.altitude = [1:1:300];
            
        end
        
        % For all depths for given lon lat location to find power
        function powPercFull = kitePowPerc(app,magPerc,lonIdx,latIdx)
            PowSurf = app.PowSurf;
            
            % Loop Through all Depths for given lon lat location to find power 
            for kk = 1:size(app.ALL.depth,1)
                if app.ALL.depth(kk) >= 0
                    DepthIdx = find(app.PowSurf.altitude == app.ALL.altitude(lonIdx,latIdx,kk));
                    
                    if isempty(DepthIdx)
                        powPercFull(kk) = NaN;
                        
                    elseif app.ALL.mag_ss_use(kk) == 0
                        powPercFull(kk) = 0  ;
                        
                    elseif magPerc(kk) == 0
                        powPercFull(kk) = 0  ;
                        
                    else
                        powPercFull(kk) = interp1( PowSurf.flwSpd',...
                            PowSurf.Pnet(:,DepthIdx),magPerc(kk));
                    end
                end
            end
            powPercFull = powPercFull';
        end
        
        % Function used in Main data process function
        % Used to find power
        function [pow,powFull] = kitepow(app,all,PowSurf,Altitude,f)
            pow = NaN*(zeros(size(all.lon,1),size(all.lat,1),size(all.depth,1),size(all.mag_ss,2))+1);
            %     pause(2)
            %     disp(size(pow))
             
            variable = 1/size(all.lon,1)/size(all.lat,1)/size(all.depth,1);
            variable = variable*.4;
            count = .3;
            waitbar(count,f,"Calculating Powers");
            for ii = 1:size(all.lon,1)
                for jj = 1:size(all.lat,1)
                    for kk = 1:size(all.depth,1)
                        for ss = 1:size(all.mag_ss_use,2)
                            %                     disp([ii,jj,kk,ss]);
                            if all.depth(kk) >= abs(app.mkDepth)
                                DepthIdx = find(PowSurf.altitude == Altitude(ii,jj,kk));
                                if isempty(DepthIdx)
                                    pow(ii,jj,kk,ss) = NaN;
                                    %                                 disp(size(pow))
                                elseif all.mag_ss_use(ss) == 0
                                    pow(ii,jj,kk,ss)=0;
                                    
                                else
                                    pow(ii,jj,kk,ss) = interp1( PowSurf.flwSpd' , PowSurf.Pnet(:,DepthIdx) , all.mag_ss_use(ss) );
                                    
                                    %                                 disp(size(pow))
                                end
                            else
                                %                         disp(size(pow))
                            end
                            
                            if all.depth(kk) >= 0
                                DepthIdx = find(PowSurf.altitude == Altitude(ii,jj,kk));
                                if isempty(DepthIdx)
                                    powFull(ii,jj,kk,ss) = NaN;
                                    %                                 disp(size(pow))
                                elseif all.mag_ss_use(ss) == 0
                                    powFull(ii,jj,kk,ss)=0;
                                    
                                else
                                    powFull(ii,jj,kk,ss) = interp1( PowSurf.flwSpd' , PowSurf.Pnet(:,DepthIdx) , all.mag_ss_use(ss) );
                                    
                                    %                                 disp(size(pow))
                                end
                            else
                                %                         disp(size(pow))
                            end
                            
                        end
                        count = count + variable;
                        waitbar(count,f,"Calculating Powers");
                    end
                end
            end
            pow(pow < 0) = 0;
        end
        
        % This is main function that process Data
        function processData(app,event)
            
            try
                close(f);
            end
            
%%%%%%%%%%%%Variables saved before being erased%%%%%%%%%%%%%%%%%%%%%%%%
            try
                allSave(1).path     = app.ALL.path;
            end
            
            % Clear Variables for multiple loads
            app.ALL             = struct([]) ;
            app.Flow            = struct([]) ;
            app.mkDepth         = struct([]) ;
            app.groundMaskDepth = struct([]) ;
            Months              = [];
            file                = [];
            
            % Find user variables for kite depth and ground mask
            minKiteDepth(app)
            gndMaskDepth(app)
            
            f = waitbar(0,'Loading your Data...');
            
            %% Load Power Curve
            PowSurf = app.PowSurf;
            
            %% Load NetCDF Flow Data
            ALL = app.ALL;

%%%%%%%%%%%%Reload the erased variables%%%%%%%%%%%%%%%%%%%%%%%%
            try
                ALL(1).path = allSave(1).path;

                % changing path
                cd(ALL.path)
            end
            
            % Gathering list of mean and standard deviation files
            ALL.list_mn = dir('**/*mn.nc');
            ALL.list_sd = dir('**/*sd.nc');
            
            % List of months in year
            monthList = {'JAN';'FEB';'MAR';'APR';'MAY';'JUN';'JUL';'AUG';'SEP';'OCT';'NOV';'DEC'};            
            
            Months = struct;
            
            ALL.time = [];
            ALL.umonths = [];
            
            % loop through list of mag mean NetCDF files and load data into matlab
            for ii = 1:numel(ALL.list_mn)
                
                file=fullfile(ALL.list_mn(ii).folder,ALL.list_mn(ii).name);
                
                info_mn = ncinfo(file);
                ALL.time = [ALL.time; info_mn.Attributes(14).Value];
                YEAR = ALL.time(ii,1:4);
                MONTH = monthList{str2double(ALL.time(ii,6:7))};
                ALL.umonths = [ALL.umonths; [MONTH '_' YEAR]];
                MONTH = ALL.umonths(ii,:);
                
                Months.(MONTH).info_mn = info_mn;
                Months.(MONTH).vars_mn = {Months.(MONTH).info_mn.Variables.Name};
                
                for jj = 1:numel(Months.(MONTH).vars_mn)
                    eval(sprintf('Months.(MONTH).%s = ncread(''%s'',''%s'');',Months.(MONTH).vars_mn{jj},file,Months.(MONTH).vars_mn{jj}));
                end
                
                Months.(MONTH).mag_mn = Months.(MONTH).water_velmag_mean;
                
                % Finding NaN values in Data and collecting indices
                % NaNs are selected based on unreasonable values of flow
                % (i.e. -9 m/s is bogus)
                Months.(MONTH).mag_mn(Months.(MONTH).mag_mn<-9)=NaN;
                Months.(MONTH).nanidx = find(isnan(Months.(MONTH).mag_mn));
                
                Months.(MONTH).mag_mn_min = nanmin(Months.(MONTH).mag_mn,[],'all');
                Months.(MONTH).mag_mn_max = nanmax(Months.(MONTH).mag_mn,[],'all');
                
                ALL.mag_mn_monthly_min(ii)=Months.(MONTH).mag_mn_min;
                ALL.mag_mn_monthly_max(ii)=Months.(MONTH).mag_mn_max;
                
                
                % loop through list of mag mean NetCDF files and load data into matlab
                file = fullfile(ALL.list_sd(ii).folder,ALL.list_sd(ii).name);
                
                info_sd = ncinfo(file);
    
                Months.(MONTH).info_sd = info_sd;
                Months.(MONTH).vars_sd = {Months.(MONTH).info_sd.Variables.Name};
                
                for jj = 1:numel(Months.(MONTH).vars_mn)
                    eval(sprintf('Months.(MONTH).%s = ncread(''%s'',''%s'');',Months.(MONTH).vars_sd{jj},file,Months.(MONTH).vars_sd{jj}));
                end
                
                
                Months.(MONTH).mag_sd = Months.(MONTH).water_velmag_stddev;
                
                %  Applying NaN indices detected in previous loop
                %  to Standard Deviation data
                Months.(MONTH).mag_sd(Months.(MONTH).nanidx) = NaN;
                
                Months.(MONTH).mag_sd_min = nanmin(Months.(MONTH).mag_sd,[],'all');
                Months.(MONTH).mag_sd_max = nanmax(Months.(MONTH).mag_sd,[],'all');
                
                ALL.mag_sd_monthly_min(ii)=Months.(MONTH).mag_sd_min;
                ALL.mag_sd_monthly_max(ii)=Months.(MONTH).mag_sd_max;
                
            end
            monthList = cellstr(ALL.umonths);
            app.monthList = monthList;
            
            % Checking min/max values of flow to determine appropriate sample set range
            % for following analysis
            ALL.mag_pos_min = ALL.mag_mn_monthly_min - ALL.mag_sd_monthly_max;
            ALL.mag_pos_max = ALL.mag_mn_monthly_max + ALL.mag_sd_monthly_max;
            
            % ALL structure contains variables that are consistent across months and/or
            % useful for ground determination/plotting
            
            ALL.lon = Months.(monthList{1}).lon;
            ALL.lat = Months.(monthList{1}).lat;
            ALL.depth = Months.(monthList{1}).depth;
            ALL.depth_ft = distdim(ALL.depth,'m','ft');
            
            
            if app.runLong == true
                ALL.idepth = [ALL.depth(1):1:ALL.depth(end)]';
            end
            
            %% finding ground
            for ii = 1:size(ALL.lon,1)
                for jj = 1:size(ALL.lat,1)
                    
                    try
                        ALL.ground_depth_idx(ii,jj) = find(~isnan(Months.(monthList{1}).mag_mn(ii,jj,:)),1,'last')+1;
                    catch
                        ALL.ground_depth_idx(ii,jj) = 1;
                    end
                    
                    if ALL.ground_depth_idx(ii,jj) > size(ALL.depth,1)
                        ALL.ground_depth_idx(ii,jj) = size(ALL.depth,1);
                    end
                                 
                    ALL.ground_depth(ii,jj) = ALL.depth(ALL.ground_depth_idx(ii,jj));
                    
                    
                    if app.runLong == true
                        for kk = 1:size(ALL.idepth,1)
                            ALL.altitude(ii,jj,kk) = ALL.ground_depth(ii,jj) - ALL.idepth(kk);
                        end
                    else
                        for kk = 1:size(ALL.depth,1)
                            ALL.altitude(ii,jj,kk) = ALL.ground_depth(ii,jj) - ALL.depth(kk);
                        end
                    end
                    
                    
                    if app.runLong == true
                        ALL.ground_depth_idx(ii,jj) = find(ALL.ground_depth(ii,jj) == ALL.idepth);
                    end
                    
                end
            end
            
            if app.runLong == true
                ALL.depthOld = ALL.depth;
                ALL.depth = ALL.idepth;
            end
            
            % Determine ground mask depth and implement NaN values where appropriate
            % Generate NaN location index
            ALL.ground_mask = ALL.ground_depth;
            ALL.ground_mask(ALL.ground_mask>abs(app.groundMaskDepth))=NaN;
            ALL.ground_mask=repmat(ALL.ground_mask,1,1,size(ALL.depth,1));
            ALL.ground_mask_idx = find(isnan(ALL.ground_mask));
            [Ym,Xm,Dm] = meshgrid(ALL.lat,ALL.lon,ALL.depth);
            [gdYm,gdXm] = meshgrid(ALL.lat,ALL.lon);


            %% Generate Normal Distribution PDFs from Mean Velocities
            
            % Generate sample set of speeds to evaluate PDFs
            ALL.StepSize = 0.01;
            ALL.mag_ss = [-1:ALL.StepSize:1.5];
            
            
            waitbar(.1,f,"Generating PDF's");
            
            variable = 1/numel(monthList)/size(Months.(MONTH).mag_mn,1);
            variable = variable*.2;
            count = .1;
            
            for mm = 1:numel(monthList)
                MONTH = monthList{mm};
                
                if app.runLong == true
                    % Interpolating for every depth in 1-meter depth increments
                    for ii = 1:length(ALL.lon)
                        for jj = 1:length(ALL.lat)
                            Months.(MONTH).mag_mn_int(ii,jj,:) = interp1(ALL.depthOld,squeeze(Months.(MONTH).mag_mn(ii,jj,:)),ALL.depth);
                            Months.(MONTH).mag_sd_int(ii,jj,:) = interp1(ALL.depthOld,squeeze(Months.(MONTH).mag_sd(ii,jj,:)),ALL.depth);
                        end
                    end
                    Months.(MONTH).mag_mn=Months.(MONTH).mag_mn_int;
                    Months.(MONTH).mag_sd=Months.(MONTH).mag_sd_int;
                end
                
                % pre-allocating for speed
                Months.(MONTH).mag_pdf = zeros(size(Months.(MONTH).mag_mn,1),size(Months.(MONTH).mag_mn,2),size(Months.(MONTH).mag_mn,3),size(ALL.mag_ss,2));
                
                % generating PDF for each position in grid for each month
                for ii = 1:size(Months.(MONTH).mag_mn,1)
                    for jj = 1:size(Months.(MONTH).mag_mn,2)
                        for kk = 1:size(Months.(MONTH).mag_mn,3)
                            Months.(MONTH).mag_pdf(ii,jj,kk,:) = normpdf(ALL.mag_ss,Months.(MONTH).mag_mn(ii,jj,kk),Months.(MONTH).mag_sd(ii,jj,kk));
                        end
                    end
                    count = count+variable;
                    waitbar(count,f,"Generating PDF's");
                end
                
                % calculating probability of each sample speed at each location for
                % each month
                Months.(MONTH).mag_prob = Months.(MONTH).mag_pdf*ALL.StepSize;
                
                Months.(MONTH).mag_prob_min = nanmin(Months.(MONTH).mag_prob,[],'all');
                Months.(MONTH).mag_prob_max = nanmax(Months.(MONTH).mag_prob,[],'all');
                
            end


            %%  Calculate possible powers at sample set flow magnitudes
            
            % Magnitudes can only be positive and within the limits of the Kite
            ALL.mag_ss_use = abs(ALL.mag_ss);
            ALL.mag_ss_use(ALL.mag_ss_use<.1)=0;
            ALL.mag_ss_use(ALL.mag_ss_use>.5)=.5;
            
            % Generate possible powers over flow sample set
            [ALL.pow_ss,ALL.powF_ss] = kitepow(app,ALL,PowSurf,ALL.altitude,f);
            
            % Find NaN power values from sample set due to altitude limitations of kite
            ALL.pow_nan_idx = find(isnan(ALL.pow_ss(:)));
            
            ALL.pow_ss_nand = ALL.pow_ss(:);
            ALL.pow_ss_nand(ALL.pow_nan_idx) = NaN;
            
            ALL.pow_ss = reshape(ALL.pow_ss_nand,[size(ALL.lon,1),size(ALL.lat,1),size(ALL.depth,1),size(ALL.mag_ss,2)]);
            
            ALL.pow_ss_min = nanmin(ALL.pow_ss,[],'all');
            ALL.pow_ss_max = nanmax(ALL.pow_ss,[],'all');
            
            % Setting query values for Percentile function
            qPerc = [25;50;75;95];
            
            
            ALL.powF_nan_idx = find(isnan(ALL.powF_ss(:)));
            
            ALL.powF_ss_nand = ALL.powF_ss(:);
            ALL.powF_ss_nand(ALL.powF_nan_idx) = NaN;
            
            ALL.powF_ss = reshape(ALL.powF_ss_nand,[size(ALL.lon,1),size(ALL.lat,1),size(ALL.depth,1),size(ALL.mag_ss,2)]);
            
            ALL.powF_ss_min = nanmin(ALL.powF_ss,[],'all');
            ALL.powF_ss_max = nanmax(ALL.powF_ss,[],'all');
            
            
            variable = 1/numel(monthList)/size(Months.(MONTH).mag_mn,1);
            variable = variable*.1;
            count = .7;
            
            for mm = 1:numel(monthList)
                MONTH = monthList{mm};
                
                % expected power by flow magnitude possibility at each location by month
                Months.(MONTH).pow_expected = Months.(MONTH).mag_prob.*ALL.pow_ss;
                
                % Expected Mean Power at each Location by Month
                for ii = 1:size(Months.(MONTH).pow_expected,1)
                    for jj = 1:size(Months.(MONTH).pow_expected,2)
                        for kk = 1:size(Months.(MONTH).pow_expected,3)
                            
                            % Pow mean as a weighted average based on expected
                            % percentages of velocity
                            Months.(MONTH).pow_mn(ii,jj,kk)=sum(squeeze(Months.(MONTH).pow_expected(ii,jj,kk,:)));
                            if isnan(Months.(MONTH).pow_mn(ii,jj,kk))
                                Months.(MONTH).pow_mn_nancheck(ii,jj,kk) = 1;
                            else
                                Months.(MONTH).pow_mn_nancheck(ii,jj,kk) = 0;
                            end
                            
                            
                        end
                    end
                    count = count+variable;
                    waitbar(count,f,"Applying Probabilities");
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                % expected power by flow magnitude possibility at each location by month
                Months.(MONTH).powF_expected = Months.(MONTH).mag_prob.*ALL.powF_ss;
                
                % Expected Mean Power at each Location by Month
                for ii = 1:size(Months.(MONTH).powF_expected,1)
                    for jj = 1:size(Months.(MONTH).powF_expected,2)
                        for kk = 1:size(Months.(MONTH).powF_expected,3)
                            
                            % Pow mean as a weighted average based on expected
                            % percentages of velocity
                            Months.(MONTH).powF_mn(ii,jj,kk)=sum(squeeze(Months.(MONTH).powF_expected(ii,jj,kk,:)));
                            if isnan(Months.(MONTH).powF_mn(ii,jj,kk))
                                Months.(MONTH).powF_mn_nancheck(ii,jj,kk) = 1;
                            else
                                Months.(MONTH).powF_mn_nancheck(ii,jj,kk) = 0;
                            end
                            
                            
                        end
                    end
                end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                % Masking unusable power at locations due to depth limitations
                Months.(MONTH).pow_mn(ALL.ground_mask_idx)=NaN;
                
                % Generating a mag mean var with the same NaN masking as the power var
                Months.(MONTH).mag_mn_nand = Months.(MONTH).mag_mn;
                Months.(MONTH).mag_mn_nand(isnan(Months.(MONTH).pow_mn)) = NaN;
                
                % Checking min/max power over whole set for determining plot bounds for
                % figures
                Months.(MONTH).pow_mn_min = nanmin(Months.(MONTH).pow_mn,[],'all');
                Months.(MONTH).pow_mn_max = nanmax(Months.(MONTH).pow_mn,[],'all');
                ALL.pow_mn_monthly_min(mm)=Months.(MONTH).pow_mn_min;
                ALL.pow_mn_monthly_max(mm)=Months.(MONTH).pow_mn_max;
                
                % Max Mean Power in each water column and associated indices
                [Months.(MONTH).pow_mn_opt_const_depth, Months.(MONTH).pow_mn_opt_const_depth_idx] = nanmax(Months.(MONTH).pow_mn,[],3);
                
                %     Months.(MONTH).mag_mn_opt_const_depth = Months.(MONTH).mag_mn_nand(Months.(MONTH).pow_mn_opt_const_depth_idx);
                
                for ii = 1:length(ALL.lon)
                    for jj = 1:length(ALL.lat)
                        Months.(MONTH).mag_mn_opt_const_depth(ii,jj) = Months.(MONTH).mag_mn_nand(ii,jj,Months.(MONTH).pow_mn_opt_const_depth_idx(ii,jj));
                    end
                end
                
                % Calculating the Percentiles for Power and Flow Magnitude assuming the same
                % viability in lat/lon/depth
                Months.(MONTH).mag_mn_opt_const_depth_Perc = prctile(Months.(MONTH).mag_mn_opt_const_depth(~isnan(Months.(MONTH).mag_mn_opt_const_depth)),qPerc);
                Months.(MONTH).pow_mn_opt_const_depth_Perc = prctile(Months.(MONTH).pow_mn_opt_const_depth(~isnan(Months.(MONTH).pow_mn_opt_const_depth)),qPerc);
                
            end
            
            waitbar(.9,f,'Position Optimization');
            
            for mm = 1:numel(monthList)
                MONTH = monthList{mm};
                ALL.pow_Perc(:,mm) = Months.(MONTH).pow_mn_opt_const_depth_Perc;
                ALL.vel_Perc(:,mm) = Months.(MONTH).mag_mn_opt_const_depth_Perc;
                ALL.pow_mn_opt_const_depth(:,:,mm) = Months.(MONTH).pow_mn_opt_const_depth;
                %ALL.magPercMax(:,mm) = nanmax(norminv(.95,Months.(MONTH).mag_mn,Months.(MONTH).mag_sd),[],'all');
            end
            app.magPercMax = 'Not Defined ~line 681';%max(ALL.magPercMax);
            
            ALL.pow_yearly_mn_opt_const_depth = nanmean(ALL.pow_mn_opt_const_depth,3);
            ALL.pow_yearly_mn_opt_const_depth_max = nanmax(ALL.pow_yearly_mn_opt_const_depth,[],'all');
            [ALL.pow_yearly_mn_opt_const_depth_max_lon_idx, ALL.pow_yearly_mn_opt_const_depth_max_lat_idx] = find(ALL.pow_yearly_mn_opt_const_depth == ALL.pow_yearly_mn_opt_const_depth_max);           
            
            try %Check Ground Mask for correct value
                ALL.pow_yearly_mn_opt_const_depth_max_ground_idx = find(ALL.depth == ALL.ground_depth(ALL.pow_yearly_mn_opt_const_depth_max_lon_idx,ALL.pow_yearly_mn_opt_const_depth_max_lat_idx));
            catch
                fig = uifigure;
                if abs(app.groundMaskDepth)<=abs(app.mkDepth)
                    uialert(fig,'Mask Depth Above Minimum Operating Kite Depth','Invalid Parameters');
                else
                    uialert(fig,'Entire Sample Area Deeper Than Ground Mask','Invalid Parameters');
                end
                return
            end
            waitbar(1,f,'Finishing');
            
            %% Put Data under ALL
            ALL.pow_mn_min = nanmin(ALL.pow_mn_monthly_min);
            ALL.pow_mn_max = nanmax(ALL.pow_mn_monthly_max);
            ALL.Dm    = Dm   ; % Depth Matrix
            ALL.gdXm  = gdXm ; %
            ALL.gdYm  = gdYm ; %
            ALL.qPerc = qPerc;
            ALL.Xm    = Xm   ;
            ALL.Ym    = Ym    ;
            
            %% Final Data Outputs
            app.ALL       = ALL      ;
            %app.PowSurf  = PowSurf ;
            app.monthList = monthList;
            app.Months    = Months   ;
            
            multipler_for_sd = 3;
            for mm = 1:numel(monthList)
                MONTH = monthList{mm};
                tempMag = squeeze(Months.(MONTH).mag_mn(ALL.pow_yearly_mn_opt_const_depth_max_lon_idx,ALL.pow_yearly_mn_opt_const_depth_max_lat_idx,:))+...
                                squeeze(Months.(MONTH).mag_sd(ALL.pow_yearly_mn_opt_const_depth_max_lon_idx,ALL.pow_yearly_mn_opt_const_depth_max_lat_idx,:)).*multipler_for_sd
                tempMag = abs(tempMag);
                tempMag(tempMag<.1) = 0 ;
                tempMag(tempMag>.5) = .5;
                            
                ALL.powPercMax(:,mm) = kitePowPerc(app,tempMag ...
                    ,ALL.pow_yearly_mn_opt_const_depth_max_lon_idx,ALL.pow_yearly_mn_opt_const_depth_max_lat_idx)
            end
            app.powPercMax = nanmax(ALL.powPercMax,[],'all');
            
            monthDropDown(app)
            
            close(f)
            
        end
        
        % Sets the app layout
        function myUpdateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {526, 526, 526};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {526, 526};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {185, '1x', '1x'};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
        
        % Finds the power
        function percentileFun(app)
            MONTH = app.monthList{app.monthIndex};
            lonIdx = app.ALL.pow_yearly_mn_opt_const_depth_max_lon_idx;
            latIdx = app.ALL.pow_yearly_mn_opt_const_depth_max_lat_idx;
            gndIdx = app.ALL.pow_yearly_mn_opt_const_depth_max_ground_idx;
            
            percData = app.PercentileEditField.Value/100;                   % Percentiles From Textbox
            meanData = squeeze(app.Months.(MONTH).mag_mn(lonIdx,latIdx,:)); % mean
            stdData  = squeeze(app.Months.(MONTH).mag_sd(lonIdx,latIdx,:)); % Standard Deviation   
            
            %Find Vel
            app.magPerc    = norminv(percData,meanData,stdData);
            
            app.magPerc = abs(app.magPerc);
            app.magPerc(app.magPerc<.1) = 0 ; %% HARDCODED BOUNDS!! DETERMINED BY KITE DESIGN
            app.magPerc(app.magPerc>.5) = .5; %% HARDCODED BOUNDS!!
            
            %Find Power from curve and Vel
            app.powPerc = kitePowPerc(app,app.magPerc,lonIdx,latIdx);
            
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                close(f);
            end
            minKiteDepth(app)
            gndMaskDepth(app)
            app.monthList = {'January','Febuary','March','April','May','June','July','August','September','October','November','December'};
            app.dataInput = [0,0];
            app.QuickProcessButton.Enable             = 'off';
            app.ProcessButton_2.Enable         = 'off';
            app.GenerateHistogramButton.Enable   = 'off';
            app.GenerateMapButton.Enable         = 'off';
            app.PowerkWButton.Enable             = 'off';
            app.VelocitymsButton.Enable          = 'off';
            app.thPercentileSiteSelectionButton.Enable        = 'off';
            app.thPercentileSiteSelectionButton_2.Enable      = 'off';
            app.thPercentileSiteSelectionButton_3.Enable      = 'off';
            app.thPercentileSiteSelectionButton_4.Enable      = 'off';
            app.DropDown.Enable                  = 'off';
            app.SaveFigureImagespngButton.Enable = 'off';
            app.SaveResultsmatButton.Enable      = 'off';
            app.GenerateHistogramButton.Enable   = 'off';
            app.GenerateMapButton.Enable         = 'off';
            
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @myUpdateAppLayout, true);
            
            drawnow;
            app.UIFigure.WindowState = 'maximized';
            
            unitChange(app)
            unitMultiplerUpdate(app)
            
        end

        % Button pushed function: SelectFlowDatancButton
        function selectFlowDataButtonPush(app, event)
            loadFlowData(app);
            figure(app.UIFigure)
            app.dataInput(1) = 1;
            
            if sum(app.dataInput) == 2
                app.QuickProcessButton.Enable        = 'on';
                app.GenerateHistogramButton.Enable   = 'on';
                app.GenerateMapButton.Enable         = 'on';
                app.PowerkWButton.Enable             = 'on';
                app.VelocitymsButton.Enable          = 'on';
                app.thPercentileSiteSelectionButton.Enable        = 'on';
                app.thPercentileSiteSelectionButton_2.Enable      = 'on';
                app.thPercentileSiteSelectionButton_3.Enable      = 'on';
                app.thPercentileSiteSelectionButton_4.Enable      = 'on';
                app.DropDown.Enable                  = 'on';
                app.SaveFigureImagespngButton.Enable = 'on';
                app.SaveResultsmatButton.Enable      = 'on';
                app.GenerateHistogramButton.Enable   = 'on';
                app.GenerateMapButton.Enable         = 'on';
            else
                app.QuickProcessButton.Enable        = 'off';
                app.GenerateHistogramButton.Enable   = 'off';
                app.GenerateMapButton.Enable         = 'off';
                app.PowerkWButton.Enable             = 'off';
                app.VelocitymsButton.Enable          = 'off';
                app.thPercentileSiteSelectionButton.Enable        = 'off';
                app.thPercentileSiteSelectionButton_2.Enable      = 'off';
                app.thPercentileSiteSelectionButton_3.Enable      = 'off';
                app.thPercentileSiteSelectionButton_4.Enable      = 'off';
                app.DropDown.Enable                  = 'off';
                app.SaveFigureImagespngButton.Enable = 'off';
                app.SaveResultsmatButton.Enable      = 'off';
                app.GenerateHistogramButton.Enable   = 'off';
                app.GenerateMapButton.Enable         = 'off';
            end
        end

        % Button pushed function: LoadPowerSurfacematButton
        function loadPowerCurveButtonPush(app, event)
            loadPowSurfData(app)
            figure(app.UIFigure)
            app.dataInput(2) = 1;
            
            if sum(app.dataInput) == 2
                app.QuickProcessButton.Enable        = 'on';
                app.ProcessButton_2.Enable           = 'on';
                app.GenerateHistogramButton.Enable   = 'on';
                app.GenerateMapButton.Enable         = 'on';
                app.PowerkWButton.Enable             = 'on';
                app.VelocitymsButton.Enable          = 'on';
                app.thPercentileSiteSelectionButton.Enable        = 'on';
                app.thPercentileSiteSelectionButton_2.Enable      = 'on';
                app.thPercentileSiteSelectionButton_3.Enable      = 'on';
                app.thPercentileSiteSelectionButton_4.Enable      = 'on';
                app.DropDown.Enable                  = 'on';
                app.SaveFigureImagespngButton.Enable = 'on';
                app.SaveResultsmatButton.Enable      = 'on';
                app.GenerateHistogramButton.Enable   = 'on';
                app.GenerateMapButton.Enable         = 'on';
            else
                app.QuickProcessButton.Enable        = 'off';
                app.ProcessButton_2.Enable           = 'off';
                app.GenerateHistogramButton.Enable   = 'off';
                app.GenerateMapButton.Enable         = 'off';
                app.PowerkWButton.Enable             = 'off';
                app.VelocitymsButton.Enable          = 'off';
                app.thPercentileSiteSelectionButton.Enable        = 'off';
                app.thPercentileSiteSelectionButton_2.Enable      = 'off';
                app.thPercentileSiteSelectionButton_3.Enable      = 'off';
                app.thPercentileSiteSelectionButton_4.Enable      = 'off';
                app.DropDown.Enable                  = 'off';
                app.SaveFigureImagespngButton.Enable = 'off';
                app.SaveResultsmatButton.Enable      = 'off';
                app.GenerateHistogramButton.Enable   = 'off';
                app.GenerateMapButton.Enable         = 'off';
            end
        end

        % Callback function
        function plotDepthProfiles(app, event)
            
            lineWidth = 2;
            
            ALL = app.ALL;
            mkDepth = app.mkDepth;
            
            %% Index Positions
            latIdx = app.ALL.pow_yearly_mn_opt_const_depth_max_lat_idx;
            lonIdx = app.ALL.pow_yearly_mn_opt_const_depth_max_lon_idx;
            gndIdx = app.ALL.pow_yearly_mn_opt_const_depth_max_ground_idx;
            
            Months = app.Months;
            MONTH = app.monthList{app.monthIndex};
           
            lastGoodIdx = find(~isnan(Months.(MONTH).mag_mn(lonIdx,latIdx,:)),1,'last');
            
            % Plot Power Profiles
            
            for ii = 1:12
                maxPower(ii) = max(max(Months.(app.monthList{ii}).powF_mn(lonIdx,latIdx,1:gndIdx)));
            end
            maxPower = app.unit.powerMultipler.*max(maxPower);
            
            plot(app.UIAxes3_2,app.unit.powerMultipler.*squeeze(Months.(MONTH).powF_mn(lonIdx,latIdx,1:gndIdx)),-app.unit.distanceMultipler.*ALL.depth(1:gndIdx),'color', [0 1 1],'LineWidth',lineWidth);
            hold(app.UIAxes3_2,'on')
            plot(app.UIAxes3_2,app.unit.powerMultipler.*squeeze(Months.(MONTH).pow_mn(lonIdx,latIdx,1:gndIdx)),-app.unit.distanceMultipler.*ALL.depth(1:gndIdx),'b','LineWidth',lineWidth);
            
            % Plot Percentile
            plot(app.UIAxes3_2,app.unit.powerMultipler.*app.powPerc,-app.unit.distanceMultipler.*ALL.depth,'m--','LineWidth',lineWidth);
            
            [maxPowerAchieve,Index] = max(Months.(MONTH).pow_mn(lonIdx,latIdx,1:gndIdx));
            maxPowerAchieve = app.unit.powerMultipler.*maxPowerAchieve;
            maxPowerDepth   = app.unit.distanceMultipler.*-abs(ALL.depth(Index));
             
            minKiteDepth = -abs(app.unit.distanceMultipler.*ALL.depth(find(~isnan(app.unit.powerMultipler.*squeeze(Months.(MONTH).pow_mn(lonIdx,latIdx,1:gndIdx))), 1)));
            
            yline(app.UIAxes3_2,minKiteDepth,'r')
            yline(app.UIAxes3_2,-app.unit.distanceMultipler.*ALL.depth(lastGoodIdx),'k')
            fill(app.UIAxes3_2,[0,100,100,0],[-app.unit.distanceMultipler.*ALL.depth(gndIdx),-app.unit.distanceMultipler.*ALL.depth(gndIdx),-10^4,-10^4],'k')
            
            plot(app.UIAxes3_2,linspace(0,maxPowerAchieve,100),maxPowerDepth*ones(1,100),'color',[0 0.5 0],'linestyle','--','LineWidth',lineWidth);
            plot(app.UIAxes3_2,maxPowerAchieve*ones(1,100),linspace(maxPowerDepth,-2000,100),'color',[0 0.5 0],'linestyle','--','LineWidth',lineWidth);


            ylim(app.UIAxes3_2,[-app.unit.distanceMultipler.*ALL.depth(gndIdx)-20 0])
            
            %xlim(app.UIAxes3_2,[0 powPercMax = kitePowPerc(app,magPerc,lonIdx,latIdx)])
            
            xlim(app.UIAxes3_2,[0 app.unit.powerMultipler.*app.powPercMax])
            %xlim(app.UIAxes3_2,[0 max(maxPower)])
            
            grid(app.UIAxes3_2,'on','minor')
            xlabel(app.UIAxes3_2,strcat("Power (",convertCharsToStrings(app.unit.powerUnitTitle),")"))
            legend(app.UIAxes3_2,{['Power Above' char(10) 'Min Kite Depth'],['Kite Power'],sprintf('Percentile (%g %%)\nPower',app.PercentileEditField.Value),'Min Kite Depth',['Deepest Datapoint' char(10) 'in Water Column'],'Aprox. Ocean Floor',sprintf('Max Power \n %.4g %s \n Depth %g %s',maxPowerAchieve,strcat("(",convertCharsToStrings(app.unit.powerUnitTitle),")"),maxPowerDepth,strcat("(",convertCharsToStrings(app.unit.distanceUnitTitle),")"))},'Location','best')
            hold(app.UIAxes3_2,'off')
            
            title(app.UIAxes3, {'Best Yearly '; 'Column Velocity ';strcat("vs. Depth (",convertCharsToStrings(app.unit.distanceUnitTitle),")");sprintf('[Lat,Lon] = [%g,%g]',ALL.lon(lonIdx),ALL.lat(latIdx))})
            title(app.UIAxes3_2, {'Best Yearly '; 'Column Power ';strcat("vs. Depth (",convertCharsToStrings(app.unit.distanceUnitTitle),")");sprintf('[Lat,Lon] = [%g,%g]',ALL.lon(lonIdx),ALL.lat(latIdx))})
            
            
            % Plotting velocity profiles
            for ii = 1:12
                maxVelocity(ii) = max(max(Months.(app.monthList{ii}).mag_mn(lonIdx,latIdx,1:gndIdx)));
                maxVelocityStd(ii) =  max(max(Months.(app.monthList{ii}).mag_sd(lonIdx,latIdx,1:gndIdx)));
            end
            maxVelocity = max(maxVelocity);
            maxVelocityStd = max(maxVelocityStd);
            
            maxVelocity = app.unit.velocityMultipler.*maxVelocity+app.unit.velocityMultipler.*maxVelocityStd;
                        
            plot(app.UIAxes3,app.unit.velocityMultipler.*squeeze(Months.(MONTH).mag_mn(lonIdx,latIdx,1:gndIdx)),-app.unit.distanceMultipler.*ALL.depth(1:gndIdx),'b','LineWidth',lineWidth);
            hold(app.UIAxes3,'on')
            plot(app.UIAxes3,app.unit.velocityMultipler.*(squeeze(Months.(MONTH).mag_mn(lonIdx,latIdx,1:gndIdx))+squeeze(Months.(MONTH).mag_sd(lonIdx,latIdx,1:gndIdx))),-app.unit.distanceMultipler.*ALL.depth(1:gndIdx),'b--','LineWidth',lineWidth);
            plot(app.UIAxes3,app.unit.velocityMultipler.*(squeeze(Months.(MONTH).mag_mn(lonIdx,latIdx,1:gndIdx))-squeeze(Months.(MONTH).mag_sd(lonIdx,latIdx,1:gndIdx))),-app.unit.distanceMultipler.*ALL.depth(1:gndIdx),'b--','LineWidth',lineWidth);
            
                        % Plot Percentile
            plot(app.UIAxes3,app.unit.velocityMultipler.*app.magPerc,-app.unit.distanceMultipler.*ALL.depth,'--m','LineWidth',lineWidth);
            
            
            yline(app.UIAxes3,minKiteDepth,'r')
            yline(app.UIAxes3,-app.unit.distanceMultipler.*ALL.depth(lastGoodIdx),'k')
            
            ylim(app.UIAxes3,[-app.unit.distanceMultipler.*ALL.depth(gndIdx)-20 0])
            xlim(app.UIAxes3,[0 maxVelocity])
            fill(app.UIAxes3,[0,100,100,0],[-app.unit.distanceMultipler.*ALL.depth(gndIdx),-app.unit.distanceMultipler.*ALL.depth(gndIdx),-10^4,-10^4],'k')
            

            legend(app.UIAxes3,{['Monthly' char(10) 'Mean'],['Monthly' char(10) 'Mean+Std'],['Monthly' char(10) 'Mean-Std'],sprintf('Percentile (%g %%)\nFlow Speed',app.PercentileEditField.Value),'Min Kite Depth',['Deepest Datapoint' char(10) 'in Water Column'],'Aprox. Ocean Floor'},'Location','best')
            
            grid(app.UIAxes3,'on','minor')
            xlabel(app.UIAxes3,strcat("Speed (",convertCharsToStrings(app.unit.velocityUnitTitle),")"))
            hold(app.UIAxes3,'off')
                        
        end

        % Callback function: UIAxes
        function plotMain(app, event)
            
            app.UIAxes.cla;
            
            color = distinguishable_colors(app,4);
            
            lineWidth = 2;
            
            if strcmp(app.ButtonGroup.SelectedObject.Text(1:5),'Power') == 1
                % Find Variables from app
                qPerc    = app.ALL.qPerc;
                pow_Perc = app.ALL.pow_Perc*app.unit.powerMultipler ;
                
                useVector = [app.thPercentileSiteSelectionButton.Value   ;...
                    app.thPercentileSiteSelectionButton_2.Value ;...
                    app.thPercentileSiteSelectionButton_3.Value ;...
                    app.thPercentileSiteSelectionButton_4.Value ];
                
                xline(app.UIAxes,app.monthIndex,'k')
                hold(app.UIAxes,'on')
                
                clear a b c d
                a = plot(app.UIAxes,(pow_Perc(1,:).*useVector(1))','--*','color',color(1,:),'LineWidth',lineWidth);
                b = plot(app.UIAxes,(pow_Perc(2,:).*useVector(2))','--*','color',color(2,:),'LineWidth',lineWidth);
                c = plot(app.UIAxes,(pow_Perc(3,:).*useVector(3))','--*','color',color(3,:),'LineWidth',lineWidth);
                d = plot(app.UIAxes,(pow_Perc(4,:).*useVector(4))','--*','color',color(4,:),'LineWidth',lineWidth);
                plotVec = [a,b,c,d];
                legend(app.UIAxes,'Selected Month','25th Percentile site seleciton','50th Percentile site seleciton','75th Percentile site seleciton','95th Percentile site seleciton','Location','best')

                for ii = 1:length(useVector)
                    if useVector(ii) == 1
                        plotVec(ii).Annotation.LegendInformation.IconDisplayStyle = 'on';
                        plotVec(ii).Visible = 'on';
                    else
                        plotVec(ii).Annotation.LegendInformation.IconDisplayStyle = 'off';
                        plotVec(ii).Visible = 'off';
                    end
                end
                
                
                title(app.UIAxes,'Monthly Power Statistics (Optimal Depth)')
                ylabel(app.UIAxes,strcat("Monthly Expected Power (Mean) (",convertCharsToStrings(app.unit.powerUnitTitle),")"));
                %xlabel(app.UIAxes,'Month')
                grid(app.UIAxes,'on','minor')
                
                xlim(app.UIAxes,[1-.2 12+.2])
                ylim(app.UIAxes,[0 max(max(pow_Perc))])


            else
                % Find Variables from app
                vel_Perc = app.ALL.vel_Perc.*app.unit.velocityMultipler ;
                
                useVector = [app.thPercentileSiteSelectionButton.Value   ;...
                    app.thPercentileSiteSelectionButton_2.Value ;...
                    app.thPercentileSiteSelectionButton_3.Value ;...
                    app.thPercentileSiteSelectionButton_4.Value ];
                
                xline(app.UIAxes,app.monthIndex,'k')
                hold(app.UIAxes,'on')
                
                                clear a b c d
                a = plot(app.UIAxes,(vel_Perc(1,:).*useVector(1))','--*','color',color(1,:),'LineWidth',lineWidth);
                b = plot(app.UIAxes,(vel_Perc(2,:).*useVector(2))','--*','color',color(2,:),'LineWidth',lineWidth);
                c = plot(app.UIAxes,(vel_Perc(3,:).*useVector(3))','--*','color',color(3,:),'LineWidth',lineWidth);
                d = plot(app.UIAxes,(vel_Perc(4,:).*useVector(4))','--*','color',color(4,:),'LineWidth',lineWidth);
                plotVec = [a,b,c,d];
                legend(app.UIAxes,'Selected Month','25th Percentile site seleciton','50th Percentile site seleciton','75th Percentile site seleciton','95th Percentile site seleciton','Location','best')

                for ii = 1:length(useVector)
                    if useVector(ii) == 1
                        plotVec(ii).Annotation.LegendInformation.IconDisplayStyle = 'on';
                        plotVec(ii).Visible = 'on';
                    else
                        plotVec(ii).Annotation.LegendInformation.IconDisplayStyle = 'off';
                        plotVec(ii).Visible = 'off';
                    end
                end
                                
                title(app.UIAxes,'Monthly Velocity Statistics (Optimal Depth)')
                ylabel(app.UIAxes,strcat("Monthly Velocity (Mean) (",convertCharsToStrings(app.unit.velocityUnitTitle),")"));
                %xlabel(app.UIAxes,'Month')
                grid(app.UIAxes,'on','minor')
                
                xlim(app.UIAxes,[1-.2 12+.2])
                ylim(app.UIAxes,[0 max(max(vel_Perc))])
                
            end
            
            %             for ii = 1:size(app.monthList,1)
            %                 monthStr = string(app.monthList{ii});
            %                 monthStr = split(monthStr,'_');
            %                 if ii == 1
            %                     xTickMonthLabel{1,1} = monthStr;
            %                 elseif ii == size(app.monthList,1)
            %                     xTickMonthLabel{ii,1} = monthStr;
            %                 else
            %                     xTickMonthLabel{ii,1} = monthStr(1);
            %                 end
            %             end
%             
            monthSplit = split(string(app.monthList),'_')';
            monthRow = monthSplit(1,:);
            yearRow = monthSplit(2,:);
            if length(yearRow)>2
                yearRow(2:end-1) = '';
            end
%             labelArray = [monthRow;yearRow];
%             tickLabels = strtrim(sprintf('%s\\newline%s\n',labelArray{:}));
            
            tickForMain = {};
            for ii = 1:size(app.monthList,1)
                tickForMain{ii} = app.monthList{ii}(1:3);
            end
            set(app.UIAxes,'xtick',1:size(app.monthList,1),'xticklabel',tickForMain);
            %xticklabels(app.UIAxes,tickLabels)
            
            xlabel(app.UIAxes,{'Month';strcat(app.monthList{1},'-',app.monthList{end})})%sprintf(yearRow(1),))
            
            
            hold(app.UIAxes,'off')
            
        end

        % Button pushed function: QuickProcessButton
        function runButton(app, event)

            app.runLong = false;
            
            processData(app,event)
            
            try
                plotDepthProfiles(app, event)
                plotMain(app, event)
                tableEdit(app, event)
                drawnow
                set(app.UIFigure,findall(app.UIFigure,'-property','FontSize'),'FontSize',18,'fontweight','bold')
            end
        end

        % Cell edit callback: UITable
        function tableEdit(app, event)
            % Find Variables from app
            
            if strcmp(app.ButtonGroup.SelectedObject.Text(1:5),'Power') == 1
                app.UITable.Data = app.ALL.pow_Perc.*app.unit.powerMultipler;   % updat'A','B'};e data
            else
                app.UITable.Data = app.ALL.vel_Perc.*app.unit.velocityMultipler;   % updat'A','B'};e data
            end
            
            app.PowerkWButton.Text    = strcat("Power (",convertCharsToStrings(app.unit.powerUnitTitle),")");
            app.VelocitymsButton.Text = strcat("Velocity (",convertCharsToStrings(app.unit.velocityUnitTitle),")");
            
            app.UITable.ColumnName = app.monthList;
            app.UITable.RowName = {'25th','50th','75th','95th'};
        end

        % Value changed function: thPercentileSiteSelectionButton, 
        % thPercentileSiteSelectionButton_2, 
        % thPercentileSiteSelectionButton_3, 
        % thPercentileSiteSelectionButton_4
        function changePlots(app, event)
            plotDepthProfiles(app, event)
            plotMain(app, event)
            tableEdit(app, event)
            drawnow

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {526, 526, 526};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {526, 526};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {185, '1x', 266};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Callback function
        function outp(app, event)
            value = app.SelectedFilesListBox.Value;
        end

        % Value changed function: SelectedFilesListBox
        function SelectedFilesListBoxValueChanged(app, event)
            
            % Check for Flow Data
            try
                app.SelectedFilesListBox.Items{1} = app.ALL.path;
            end
            
            % Check for Power Data
            try
                app.SelectedFilesListBox.Items{2} = app.ALL.powPath;
            end
        end

        % Button pushed function: SaveResultsmatButton
        function saveData(app, event)
            Output.Flow       = app.Flow       ;
            Output.ALL        = app.ALL        ;
            Output.OutputTest = app.OutputTest ;
            Output.PowSurf   = app.PowSurf   ;
            Output.monthList  = app.monthList  ;
            Output.monthList  = app.monthList  ;
            Output.Months     = app.Months     ;
            Output.monthIndex = app.monthIndex ;
            Output.mkDepth    = app.mkDepth    ;
            Output.groundMaskDepth = app.groundMaskDepth    ;
            
            save('flowToolOutput','Output')
        end

        % Value changed function: DropDown
        function monthDropDown(app, event)

            app.DropDown.Items = app.monthList;
            app.monthIndex = find(contains(app.monthList,app.DropDown.Value));
            
            percentileFun(app)
            plotDepthProfiles(app)
            plotMain(app)
        end

        % Value changed function: MinKiteDepthmEditField_2
        function minKiteDepth(app, event)
            app.mkDepth = app.MinKiteDepthmEditField_2.Value;
            
            %-abs(app.unit.distanceMultipler.*ALL.depth(find(~isnan(app.unit.powerMultipler.*squeeze(Months.(MONTH).pow_mn(lonIdx,latIdx,1:gndIdx))), 1)));
            
        end

        % Button pushed function: SaveFigureImagespngButton
        function savePng(app, event)
            % Save Main Plot vel and power slice
            exportgraphics(app.UIAxes,'mainPlot.png')
            exportgraphics(app.UIAxes3,'velPlot.png')
            exportgraphics(app.UIAxes3_2,'powPlot.png')
        end

        % Value changed function: GroundMaskDepthmEditField
        function gndMaskDepth(app, event)
            value = app.GroundMaskDepthmEditField.Value;
            app.groundMaskDepth = value;
        end

        % Selection changed function: ButtonGroup
        function selectMainPlot(app, event)
            plotMain(app)
            tableEdit(app)
            
        end

        % Button pushed function: GenerateHistogramButton
        function plotHistogram(app, event)
            figure(2)
            sp1 = 1;
            sp2 = 2;
            for ii = 1:numel(app.monthList)
                maxVelocity(ii) = max(max(app.Months.(app.monthList{ii}).mag_mn_opt_const_depth(~isnan(app.Months.(app.monthList{ii}).pow_mn_opt_const_depth))));
            end
                            
            for mm = 1:numel(app.monthList)
                MONTH = app.monthList{mm};
                
                edges = [0:.025:ceil(max(maxVelocity)*10)/10];%+0.0000000000001;
                
                subplot(12,2,sp1)
                hold on
                % Plotting probability histograms and converting m/s to ft/s
                histogram(app.Months.(MONTH).mag_mn_opt_const_depth(~isnan(app.Months.(MONTH).pow_mn_opt_const_depth)),edges,'Normalization','probability');
                axis([min(edges) max(edges) 0 0.4]);
                for ii = 1:length(app.ALL.qPerc);
                    MagPercLine = xline(app.Months.(MONTH).mag_mn_opt_const_depth_Perc(ii),'r');
                    MagPercLine.LineWidth = 2;
                end
                
                if mm == 1
                    title({'Mean Flow Magnitude at Each Location','Assuming Optimal Constant Depth','(Red Lines are 50th, 75th, & 95th Percentiles)'})
                end
                
                if mm == 12
                    xlabel('Monthly Mean Flow Speed (m/s)')
                end
                
                ylabel({MONTH})
                grid on
                grid minor
                hold off
                sp1 = sp1+2;


                for ii = 1:12
                    maxPower(ii) = max(max(app.Months.(app.monthList{ii}).pow_mn_opt_const_depth(~isnan(app.Months.(app.monthList{ii}).pow_mn_opt_const_depth))));
                end
                edges = [0:.025:ceil(max(maxPower)*10)/10];
                
                subplot(12,2,mm*2)
                hold on
                % Plotting probability histograms of power
                histogram(app.Months.(MONTH).pow_mn_opt_const_depth(~isnan(app.Months.(MONTH).pow_mn_opt_const_depth)),edges,'Normalization','probability');
                axis([min(edges) max(edges) 0 0.4]);
                for ii = 1:length(app.ALL.qPerc)
                    PowPercLine = xline(app.Months.(MONTH).pow_mn_opt_const_depth_Perc(ii),'r');
                    PowPercLine.LineWidth = 2;
                end
                
                if mm == 1
                    title({'Monthly Probability of Mean Power at Each Location','Assuming Optimal Constant Depth','(Red Lines are 50th, 75th, & 95th Percentiles)'},'interpreter','latex')
                end
                
                if mm == 12
                    xlabel('Monthly Mean Power (kW)')
                end
                
                grid on
                grid minor
                hold off
                sp2 = sp2+2;
            end
            
            set(gcf, 'units','normalized','outerposition',[0 0 1 1]);
            
            
        end

        % Button pushed function: GenerateMapButton
        function plotMap(app, event)
            figure(3)
            
            subplot(2,1,1)
            hold on
            
            ax1 = gca;
            surf(app.ALL.gdXm,app.ALL.gdYm,-app.ALL.ground_depth);
            colormap(ax1,'gray')
            
            ax2 = gca;
            scatter3(app.ALL.Xm(:),app.ALL.Ym(:),-app.ALL.Dm(:),100,app.Months.(app.monthList{app.monthIndex}).mag_mn(:),'filled');
            colormap(ax2,'jet')
            % ax2.Visible = 'off';
            xlabel('Longitude')
            ylabel('Latitude')
            zlabel('Depth (ft)')
            c1 = colorbar;
            ylabel(c1,'Depth (ft)')
            view(3)
            
            title(strcat('Datapoints before Ground Masking','(',app.monthList{app.monthIndex},')'))
            
            subplot(2,1,2)
            hold on
            
            ax3 = gca;
            surf(app.ALL.gdXm,app.ALL.gdYm,-app.ALL.ground_depth);
            colormap(ax3,'gray')
            
            ax4 = gca;
            scatter3(app.ALL.Xm(:),app.ALL.Ym(:),-app.ALL.Dm(:),100,app.Months.(app.monthList{app.monthIndex}).mag_mn_nand(:),'filled');
            colormap(ax4,'jet')
            % ax2.Visible = 'off';
            
            view(3)
            c2 = colorbar;
            xlabel('Longitude')
            ylabel('Latitude')
            zlabel('Depth (m)')
            ylabel(c2,'Depth (m)')
            title('Viable Datapoints after Ground Masking')
            hold off
            
            Link = linkprop([ax1,ax2,ax3,ax4],{'CameraUpVector', 'CameraPosition', 'CameraTarget', 'XLim', 'YLim', 'ZLim'});
            setappdata(gcf,'StoreTheLink',Link);
            
            set(gcf, 'units','normalized','outerposition',[0 0 1 1]);

        end

        % Selection changed function: UnitsMetricButtonGroup
        function unitChange(app, event)
            if app.MetricButton.Value==1
                app.unit.name = 'Metric';
            else
                app.unit.name = 'English';
            end
            
            app.UnitsMetricButtonGroup.Title = strcat('Units (',app.unit.name,')');
            
            try
            unitMultiplerUpdate(app)
            plotDepthProfiles(app)
            plotMain(app)
            tableEdit(app)
            end
            drawnow
            
        end

        % Button pushed function: ProcessButton_2
        function runLongButton(app, event)
            
            app.runLong = true;
            
            processData(app,event)
            
            try
                plotDepthProfiles(app, event)
                plotMain(app, event)
                tableEdit(app, event)
                drawnow
                set(app.UIFigure,findall(app.UIFigure,'-property','FontSize'),'FontSize',18,'fontweight','bold')
            end
        end

        % Value changed function: PercentileEditField
        function percentileChange(app, event)
            percentileFun(app)
            plotDepthProfiles(app)
            drawnow
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectFlowDatancButton = uibutton(app.LeftPanel, 'push');
            app.SelectFlowDatancButton.ButtonPushedFcn = createCallbackFcn(app, @selectFlowDataButtonPush, true);
            app.SelectFlowDatancButton.Text = {'Select Flow Data'; '(*.nc)'};
            app.LoadPowerSurfacematButton = uibutton(app.LeftPanel, 'push');
            app.LoadPowerSurfacematButton.ButtonPushedFcn = createCallbackFcn(app, @loadPowerCurveButtonPush, true);
            app.LoadPowerSurfacematButton.Text = {'Load Power Surface'; '(*.mat)'};
            app.QuickProcessButton = uibutton(app.LeftPanel, 'push');
            app.QuickProcessButton.ButtonPushedFcn = createCallbackFcn(app, @runButton, true);
            app.QuickProcessButton.Enable = 'off';
            app.QuickProcessButton.Text = 'Quick Process';
            app.SelectedFilesListBoxLabel = uilabel(app.LeftPanel);
            app.SelectedFilesListBoxLabel.Text = 'Selected Files';
            app.SelectedFilesListBox = uilistbox(app.LeftPanel);
            app.SelectedFilesListBox.Items = {'Item 1', 'Item 2', ''};
            app.SelectedFilesListBox.ValueChangedFcn = createCallbackFcn(app, @SelectedFilesListBoxValueChanged, true);
            app.IfnobottomdepthfordataLabel = uilabel(app.LeftPanel);
            app.IfnobottomdepthfordataLabel.Text = 'If no bottom depth for data:';
            app.GroundMaskDepthmLabel = uilabel(app.LeftPanel);
            app.GroundMaskDepthmLabel.Text = {'Ground Mask '; 'Depth (m)'};
            app.GroundMaskDepthmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.GroundMaskDepthmEditField.ValueChangedFcn = createCallbackFcn(app, @gndMaskDepth, true);
            app.GroundMaskDepthmEditField.Value = -500;
            app.MinKiteDepthmEditField_2Label = uilabel(app.LeftPanel);
            app.MinKiteDepthmEditField_2Label.Text = {'Min Kite '; 'Depth (m)'};
            app.MinKiteDepthmEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.MinKiteDepthmEditField_2.ValueChangedFcn = createCallbackFcn(app, @minKiteDepth, true);
            app.MinKiteDepthmEditField_2.Value = -200;
            app.ProcessButton_2 = uibutton(app.LeftPanel, 'push');
            app.ProcessButton_2.ButtonPushedFcn = createCallbackFcn(app, @runLongButton, true);
            app.ProcessButton_2.Enable = 'off';
            app.ProcessButton_2.Text = 'Process';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Monthly Power Statistics (Optimal Depth)', 'Interpreter', 'latex')
            xlabel(app.UIAxes, 'Month', 'Interpreter', 'latex')
            ylabel(app.UIAxes, 'Y', 'Interpreter', 'latex')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.UITable = uitable(app.CenterPanel);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @tableEdit, true);
            app.ButtonGroup = uibuttongroup(app.CenterPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @selectMainPlot, true);
            app.PowerkWButton = uitogglebutton(app.ButtonGroup);
            app.PowerkWButton.Enable = 'off';
            app.PowerkWButton.Text = 'Power (kW)';
            app.PowerkWButton.Value = true;
            app.VelocitymsButton = uitogglebutton(app.ButtonGroup);
            app.VelocitymsButton.Enable = 'off';
            app.VelocitymsButton.Text = 'Velocity (m/s)';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, {'Best Yearly '; 'Column Velocity '; 'vs. Depth (m)'}, 'Interpreter', 'latex')
            xlabel(app.UIAxes3, '', 'Interpreter', 'latex')
            ylabel(app.UIAxes3, '', 'Interpreter', 'latex')
            app.SaveResultsmatButton = uibutton(app.RightPanel, 'push');
            app.SaveResultsmatButton.ButtonPushedFcn = createCallbackFcn(app, @saveData, true);
            app.SaveResultsmatButton.Enable = 'off';
            app.SaveResultsmatButton.Text = 'Save Results (*.mat)';
            app.DisplaySettingsButtonGroup = uibuttongroup(app.RightPanel);
            app.DisplaySettingsButtonGroup.Title = 'Display Settings';
            app.thPercentileSiteSelectionButton = uibutton(app.DisplaySettingsButtonGroup, 'state');
            app.thPercentileSiteSelectionButton.ValueChangedFcn = createCallbackFcn(app, @changePlots, true);
            app.thPercentileSiteSelectionButton.Enable = 'off';
            app.thPercentileSiteSelectionButton.Text = '25th Percentile (Site Selection)';
            app.thPercentileSiteSelectionButton.Value = true;
            app.thPercentileSiteSelectionButton_2 = uibutton(app.DisplaySettingsButtonGroup, 'state');
            app.thPercentileSiteSelectionButton_2.ValueChangedFcn = createCallbackFcn(app, @changePlots, true);
            app.thPercentileSiteSelectionButton_2.Enable = 'off';
            app.thPercentileSiteSelectionButton_2.Text = '50th Percentile (Site Selection)';
            app.thPercentileSiteSelectionButton_2.Value = true;
            app.thPercentileSiteSelectionButton_3 = uibutton(app.DisplaySettingsButtonGroup, 'state');
            app.thPercentileSiteSelectionButton_3.ValueChangedFcn = createCallbackFcn(app, @changePlots, true);
            app.thPercentileSiteSelectionButton_3.Enable = 'off';
            app.thPercentileSiteSelectionButton_3.Text = '75th Percentile (Site Selection)';
            app.thPercentileSiteSelectionButton_3.Value = true;
            app.thPercentileSiteSelectionButton_4 = uibutton(app.DisplaySettingsButtonGroup, 'state');
            app.thPercentileSiteSelectionButton_4.ValueChangedFcn = createCallbackFcn(app, @changePlots, true);
            app.thPercentileSiteSelectionButton_4.Enable = 'off';
            app.thPercentileSiteSelectionButton_4.Text = '95th Percentile (Site Selection)';
            app.thPercentileSiteSelectionButton_4.Value = true;
            app.DropDown = uidropdown(app.DisplaySettingsButtonGroup);
            app.DropDown.Items = {'January', 'Febuary', 'March', 'April', 'May', 'June', 'July', 'August', 'September', 'October', 'November', 'December'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @monthDropDown, true);
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'January';
            app.SaveFigureImagespngButton = uibutton(app.RightPanel, 'push');
            app.SaveFigureImagespngButton.ButtonPushedFcn = createCallbackFcn(app, @savePng, true);
            app.SaveFigureImagespngButton.Enable = 'off';
            app.SaveFigureImagespngButton.Text = 'Save Figure Images (*.png)';
            app.UIAxes3_2 = uiaxes(app.RightPanel);
            title(app.UIAxes3_2, {'Best Yearly '; 'Column Power'; 'vs. Depth (m)'}, 'Interpreter', 'latex')
            xlabel(app.UIAxes3_2, '', 'Interpreter', 'latex')
            ylabel(app.UIAxes3_2, '', 'Interpreter', 'latex')
            app.GenerateHistogramButton = uibutton(app.RightPanel, 'push');
            app.GenerateHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @plotHistogram, true);
            app.GenerateHistogramButton.Enable = 'off';
            app.GenerateHistogramButton.Text = 'Generate Histogram';
            app.GenerateMapButton = uibutton(app.RightPanel, 'push');
            app.GenerateMapButton.ButtonPushedFcn = createCallbackFcn(app, @plotMap, true);
            app.GenerateMapButton.Enable = 'off';
            app.GenerateMapButton.Text = 'Generate Map';
            app.UnitsMetricButtonGroup = uibuttongroup(app.RightPanel);
            app.UnitsMetricButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @unitChange, true);
            app.UnitsMetricButtonGroup.Title = 'Units (Metric)';
            app.MetricButton = uitogglebutton(app.UnitsMetricButtonGroup);
            app.MetricButton.Text = 'Metric';
            app.MetricButton.Value = true;
            app.EnglishButton = uitogglebutton(app.UnitsMetricButtonGroup);
            app.EnglishButton.Text = {'English'; ''};
            app.PercentileEditFieldLabel = uilabel(app.RightPanel);
            app.PercentileEditFieldLabel.Text = 'Percentile (%)';
            app.PercentileEditField = uieditfield(app.RightPanel, 'numeric');
            app.PercentileEditField.Limits = [5 95];
            app.PercentileEditField.ValueChangedFcn = createCallbackFcn(app, @percentileChange, true);
            app.PercentileEditField.Value = 84;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Flow2Power_Tool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end